classdef pianio_interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        Image         matlab.ui.control.Image
        F3Button      matlab.ui.control.Button
        G3Button      matlab.ui.control.Button
        A3Button      matlab.ui.control.Button
        B3Button      matlab.ui.control.Button
        C4Button      matlab.ui.control.Button
        D4Button      matlab.ui.control.Button
        E4Button      matlab.ui.control.Button
        F4Button      matlab.ui.control.Button
        G4Button      matlab.ui.control.Button
        A4Button      matlab.ui.control.Button
        B4Button      matlab.ui.control.Button
        C5Button      matlab.ui.control.Button
        D5Button      matlab.ui.control.Button
        E5Button      matlab.ui.control.Button
        F5Button      matlab.ui.control.Button
        G5Button      matlab.ui.control.Button
        A5Button      matlab.ui.control.Button
        B5Button      matlab.ui.control.Button
        C6Button      matlab.ui.control.Button
        Button        matlab.ui.control.Button
        Button_2      matlab.ui.control.Button
        Button_3      matlab.ui.control.Button
        Button_4      matlab.ui.control.Button
        Button_5      matlab.ui.control.Button
        Button_6      matlab.ui.control.Button
        Button_7      matlab.ui.control.Button
        Button_8      matlab.ui.control.Button
        Button_9      matlab.ui.control.Button
        Button_10     matlab.ui.control.Button
        Button_11     matlab.ui.control.Button
        Button_12     matlab.ui.control.Button
        Button_13     matlab.ui.control.Button
        PIANOLabel    matlab.ui.control.Label
        RecordButton  matlab.ui.control.StateButton
        SHFTLabel     matlab.ui.control.Label
        ZLabel        matlab.ui.control.Label
        XLabel        matlab.ui.control.Label
        CLabel        matlab.ui.control.Label
        VLabel        matlab.ui.control.Label
        BLabel        matlab.ui.control.Label
        NLabel        matlab.ui.control.Label
        MLabel        matlab.ui.control.Label
        Label         matlab.ui.control.Label
        LLabel        matlab.ui.control.Label
        KLabel        matlab.ui.control.Label
        JLabel        matlab.ui.control.Label
        HLabel        matlab.ui.control.Label
        GLabel        matlab.ui.control.Label
        FLabel        matlab.ui.control.Label
        DLabel        matlab.ui.control.Label
        SLabel        matlab.ui.control.Label
        ALabel        matlab.ui.control.Label
        CLLabel       matlab.ui.control.Label
        QLabel        matlab.ui.control.Label
        WLabel        matlab.ui.control.Label
        ELabel        matlab.ui.control.Label
        RLabel        matlab.ui.control.Label
        TLabel        matlab.ui.control.Label
        YLabel        matlab.ui.control.Label
        ULabel        matlab.ui.control.Label
        ILabel        matlab.ui.control.Label
        OLabel        matlab.ui.control.Label
        PLabel        matlab.ui.control.Label
        INSLabel      matlab.ui.control.Label
        HMLabel       matlab.ui.control.Label
        ENDLabel      matlab.ui.control.Label
    end

    
    properties (Access = private)
        sampling_freq = 44101;
        time = 4;
        rec = 0;
        song = [];
        
    end
    
    methods (Access = private)
        
        function wave = wavefunc(app,notenum,time)
            t = 0:1/app.sampling_freq:4/time;
            %formula to determine the frequency of a note from its
            %notenumber
            freq = 440*2^((notenum - 49)/12);
            
            %code to simulate the waves of a piano
            wave = sin(1 * 2 * pi * freq * t).*exp(-0.0004 * 2 * pi * freq * t) / 1;
            wave = wave + sin(2 * 2 * pi * freq * t).*exp(-0.0004 * 2 * pi * freq * t) / 2;
            wave = wave + sin(3 * 2 * pi * freq * t).*exp(-0.0004 * 2 * pi * freq * t) / 4;
            wave = wave + sin(4 * 2 * pi * freq * t).*exp(-0.0004 * 2 * pi * freq * t) / 8;
            wave = wave + sin(5 * 2 * pi * freq * t).*exp(-0.0004 * 2 * pi * freq * t) / 16;
            wave = wave + sin(6 * 2 * pi * freq * t).*exp(-0.0004 * 2 * pi * freq * t) / 32;
            wave = wave + (wave.*wave.*wave);
            wave = wave / 6;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: F3Button
        function F3ButtonPushed(app, event)
            notenum = 33;
            wave = app.wavefunc(notenum,app.time);
            sound(wave,app.sampling_freq,24);
            if (app.rec == 1)
                app.song =[app.song wave];  
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            notenum = 34;
            wave = app.wavefunc(notenum,app.time);
            sound(wave,app.sampling_freq,24);
            if (app.rec == 1)
                app.song =[app.song wave];  
            end
        end

        % Button pushed function: G3Button
        function G3ButtonPushed(app, event)
            notenum = 35;
            wave = app.wavefunc(notenum,app.time);
            sound(wave,app.sampling_freq,24);
            if (app.rec == 1)
                app.song =[app.song wave];  
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            notenum = 36;
            wave = app.wavefunc(notenum,app.time);
            sound(wave,app.sampling_freq,24);
            if (app.rec == 1)
                app.song =[app.song wave];  
            end
        end

        % Button pushed function: A3Button
        function A3ButtonPushed(app, event)
            notenum = 37;
            wave = app.wavefunc(notenum,app.time);
            sound(wave,app.sampling_freq,24);
            if (app.rec == 1)
                app.song =[app.song wave];  
            end
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            notenum = 38;
            wave = app.wavefunc(notenum,app.time);
            sound(wave,app.sampling_freq,24);
            if (app.rec == 1)
                app.song =[app.song wave];  
            end
        end

        % Button pushed function: B3Button
        function B3ButtonPushed(app, event)
            notenum = 39;
            wave = app.wavefunc(notenum,app.time);
            sound(wave,app.sampling_freq,24);
            if (app.rec == 1)
                app.song =[app.song wave];  
            end
        end

        % Button pushed function: C4Button
        function C4ButtonPushed(app, event)
            notenum = 40;
            wave = app.wavefunc(notenum,app.time);
            sound(wave,app.sampling_freq,24);
            if (app.rec == 1)
                app.song =[app.song wave];  
            end
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            notenum = 41;
            wave = app.wavefunc(notenum,app.time);
            sound(wave,app.sampling_freq,24);
            if (app.rec == 1)
                app.song =[app.song wave];  
            end
        end

        % Button pushed function: D4Button
        function D4ButtonPushed(app, event)
            notenum = 42;
            wave = app.wavefunc(notenum,app.time);
            sound(wave,app.sampling_freq,24);
            if (app.rec == 1)
                app.song =[app.song wave];  
            end
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            notenum = 43;
            wave = app.wavefunc(notenum,app.time);
            sound(wave,app.sampling_freq,24);
            if (app.rec == 1)
                app.song =[app.song wave];  
            end
        end

        % Button pushed function: E4Button
        function E4ButtonPushed(app, event)
            notenum = 44;
            wave = app.wavefunc(notenum,app.time);
            sound(wave,app.sampling_freq,24);
            if (app.rec == 1)
                app.song =[app.song wave];  
            end
        end

        % Button pushed function: F4Button
        function F4ButtonPushed(app, event)
            notenum = 45;
            wave = app.wavefunc(notenum,app.time);
            sound(wave,app.sampling_freq,24);
            if (app.rec == 1)
                app.song =[app.song wave];  
            end
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            notenum = 46;
            wave = app.wavefunc(notenum,app.time);
            sound(wave,app.sampling_freq,24);
            if (app.rec == 1)
                app.song =[app.song wave];  
            end
        end

        % Button pushed function: G4Button
        function G4ButtonPushed(app, event)
            notenum = 47;
            wave = app.wavefunc(notenum,app.time);
            sound(wave,app.sampling_freq,24);
            if (app.rec == 1)
                app.song =[app.song wave];  
            end
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            notenum = 48;
            wave = app.wavefunc(notenum,app.time);
            sound(wave,app.sampling_freq,24);
            if (app.rec == 1)
                app.song =[app.song wave];  
            end
        end

        % Button pushed function: A4Button
        function A4ButtonPushed(app, event)
            notenum = 49;
            wave = app.wavefunc(notenum,app.time);
            sound(wave,app.sampling_freq,24);
            if (app.rec == 1)
                app.song =[app.song wave];  
            end
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            notenum = 50;
            wave = app.wavefunc(notenum,app.time);
            sound(wave,app.sampling_freq,24);
            if (app.rec == 1)
                app.song =[app.song wave];  
            end
        end

        % Button pushed function: B4Button
        function B4ButtonPushed(app, event)
            notenum = 51;
            wave = app.wavefunc(notenum,app.time);
            sound(wave,app.sampling_freq,24);
            if (app.rec == 1)
                app.song =[app.song wave];  
            end
        end

        % Button pushed function: C5Button
        function C5ButtonPushed(app, event)
            notenum = 52;
            wave = app.wavefunc(notenum,app.time);
            sound(wave,app.sampling_freq,24);
            if (app.rec == 1)
                app.song =[app.song wave];  
            end
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            notenum = 53;
            wave = app.wavefunc(notenum,app.time);
            sound(wave,app.sampling_freq,24);
            if (app.rec == 1)
                app.song =[app.song wave];  
            end
        end

        % Button pushed function: D5Button
        function D5ButtonPushed(app, event)
            notenum = 54;
            wave = app.wavefunc(notenum,app.time);
            sound(wave,app.sampling_freq,24);
            if (app.rec == 1)
                app.song =[app.song wave];  
            end
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            notenum = 55;
            wave = app.wavefunc(notenum,app.time);
            sound(wave,app.sampling_freq,24);
            if (app.rec == 1)
                app.song =[app.song wave];  
            end
        end

        % Button pushed function: E5Button
        function E5ButtonPushed(app, event)
            notenum = 56;
            wave = app.wavefunc(notenum,app.time);
            sound(wave,app.sampling_freq,24);
            if (app.rec == 1)
                app.song =[app.song wave];  
            end
        end

        % Button pushed function: F5Button
        function F5ButtonPushed(app, event)
            notenum = 57;
            wave = app.wavefunc(notenum,app.time);
            sound(wave,app.sampling_freq,24);
            if (app.rec == 1)
                app.song =[app.song wave];  
            end
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            notenum = 58;
            wave = app.wavefunc(notenum,app.time);
            sound(wave,app.sampling_freq,24);
            if (app.rec == 1)
                app.song =[app.song wave];  
            end
        end

        % Button pushed function: G5Button
        function G5ButtonPushed(app, event)
            notenum = 59;
            wave = app.wavefunc(notenum,app.time);
            sound(wave,app.sampling_freq,24);
            if (app.rec == 1)
                app.song =[app.song wave];  
            end
        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)
            notenum = 60;
            wave = app.wavefunc(notenum,app.time);
            sound(wave,app.sampling_freq,24);
            if (app.rec == 1)
                app.song =[app.song wave];  
            end
        end

        % Button pushed function: A5Button
        function A5ButtonPushed(app, event)
            notenum = 61;
            wave = app.wavefunc(notenum,app.time);
            sound(wave,app.sampling_freq,24);
            if (app.rec == 1)
                app.song =[app.song wave];  
            end
        end

        % Button pushed function: Button_13
        function Button_13Pushed(app, event)
            notenum = 62;
            wave = app.wavefunc(notenum,app.time);
            sound(wave,app.sampling_freq,24);
            if (app.rec == 1)
                app.song =[app.song wave];  
            end
        end

        % Button pushed function: B5Button
        function B5ButtonPushed(app, event)
            notenum = 63;
            wave = app.wavefunc(notenum,app.time);
            sound(wave,app.sampling_freq,24);
            if (app.rec == 1)
                app.song =[app.song wave];  
            end
        end

        % Button pushed function: C6Button
        function C6ButtonPushed(app, event)
            notenum = 64;
            wave = app.wavefunc(notenum,app.time);
            sound(wave,app.sampling_freq,24);
            if (app.rec == 1)
                app.song =[app.song wave];  
            end
        end

        % Callback function
        function RECORDButtonPushed(app, event)
            
        end

        % Callback function
        function RecordSwitchValueChanged(app, event)
            
        end

        % Value changed function: RecordButton
        function RecordButtonValueChanged(app, event)
            app.rec = app.RecordButton.Value;
            if(app.rec==0)
                audiowrite('recorded.wav',app.song,app.sampling_freq,'BitsPerSample',32);
            end
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
        switch key
        case 'shift'
        F3ButtonPushed(app, event)
        case 'z'
        G3ButtonPushed(app, event)
        case 'x'
        A3ButtonPushed(app, event)
        case 'c'
        B3ButtonPushed(app, event)
        case 'v'
        C4ButtonPushed(app, event)
        case 'b'
        D4ButtonPushed(app, event)
        case 'n'
        E4ButtonPushed(app, event)
        case 'm'
        F4ButtonPushed(app, event)
        case 'comma'
        G4ButtonPushed(app, event)
        case 'l'
        A4ButtonPushed(app, event)
        case 'k'
        B4ButtonPushed(app, event)
        case 'j'
        C5ButtonPushed(app, event)
        case 'h'
        D5ButtonPushed(app, event)
        case 'g'
        E5ButtonPushed(app, event)
        case 'f'
        F5ButtonPushed(app, event)
        case 'd'
        G5ButtonPushed(app, event)
        case 's'
        A5ButtonPushed(app, event)
        case 'a'
        B5ButtonPushed(app, event)
        case 'capslock'
        C6ButtonPushed(app, event)
        case 'q'
        ButtonPushed(app, event)
        case 'w'
        Button_2Pushed(app, event)
        case 'e'
        Button_3Pushed(app, event)
        case 'r'
        Button_4Pushed(app, event)
        case 't'
        Button_5Pushed(app, event)
        case 'y'
        Button_6Pushed(app, event)
        case 'u'
        Button_7Pushed(app, event)
        case 'i'
        Button_8Pushed(app, event)
        case 'o'
        Button_9Pushed(app, event)
        case 'p'
        Button_10Pushed(app, event)
        case 'insert'
        Button_11Pushed(app, event)
        case 'home'
        Button_12Pushed(app, event)
        case 'end'
        Button_13Pushed(app, event)
        end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'mockup-photos-5nUNdLueQio-unsplash.jpg';
            app.F3Button = uibutton(app.UIFigure, 'push');
            app.F3Button.ButtonPushedFcn = createCallbackFcn(app, @F3ButtonPushed, true);
            app.F3Button.Text = 'F3';
            app.G3Button = uibutton(app.UIFigure, 'push');
            app.G3Button.ButtonPushedFcn = createCallbackFcn(app, @G3ButtonPushed, true);
            app.G3Button.Text = 'G3';
            app.A3Button = uibutton(app.UIFigure, 'push');
            app.A3Button.ButtonPushedFcn = createCallbackFcn(app, @A3ButtonPushed, true);
            app.A3Button.Text = 'A3';
            app.B3Button = uibutton(app.UIFigure, 'push');
            app.B3Button.ButtonPushedFcn = createCallbackFcn(app, @B3ButtonPushed, true);
            app.B3Button.Text = 'B3';
            app.C4Button = uibutton(app.UIFigure, 'push');
            app.C4Button.ButtonPushedFcn = createCallbackFcn(app, @C4ButtonPushed, true);
            app.C4Button.Text = 'C4';
            app.D4Button = uibutton(app.UIFigure, 'push');
            app.D4Button.ButtonPushedFcn = createCallbackFcn(app, @D4ButtonPushed, true);
            app.D4Button.Text = 'D4';
            app.E4Button = uibutton(app.UIFigure, 'push');
            app.E4Button.ButtonPushedFcn = createCallbackFcn(app, @E4ButtonPushed, true);
            app.E4Button.Text = 'E4';
            app.F4Button = uibutton(app.UIFigure, 'push');
            app.F4Button.ButtonPushedFcn = createCallbackFcn(app, @F4ButtonPushed, true);
            app.F4Button.Text = 'F4';
            app.G4Button = uibutton(app.UIFigure, 'push');
            app.G4Button.ButtonPushedFcn = createCallbackFcn(app, @G4ButtonPushed, true);
            app.G4Button.Text = 'G4';
            app.A4Button = uibutton(app.UIFigure, 'push');
            app.A4Button.ButtonPushedFcn = createCallbackFcn(app, @A4ButtonPushed, true);
            app.A4Button.Text = 'A4';
            app.B4Button = uibutton(app.UIFigure, 'push');
            app.B4Button.ButtonPushedFcn = createCallbackFcn(app, @B4ButtonPushed, true);
            app.B4Button.Text = 'B4';
            app.C5Button = uibutton(app.UIFigure, 'push');
            app.C5Button.ButtonPushedFcn = createCallbackFcn(app, @C5ButtonPushed, true);
            app.C5Button.Text = 'C5';
            app.D5Button = uibutton(app.UIFigure, 'push');
            app.D5Button.ButtonPushedFcn = createCallbackFcn(app, @D5ButtonPushed, true);
            app.D5Button.Text = 'D5';
            app.E5Button = uibutton(app.UIFigure, 'push');
            app.E5Button.ButtonPushedFcn = createCallbackFcn(app, @E5ButtonPushed, true);
            app.E5Button.Text = 'E5';
            app.F5Button = uibutton(app.UIFigure, 'push');
            app.F5Button.ButtonPushedFcn = createCallbackFcn(app, @F5ButtonPushed, true);
            app.F5Button.Text = 'F5';
            app.G5Button = uibutton(app.UIFigure, 'push');
            app.G5Button.ButtonPushedFcn = createCallbackFcn(app, @G5ButtonPushed, true);
            app.G5Button.Text = 'G5';
            app.A5Button = uibutton(app.UIFigure, 'push');
            app.A5Button.ButtonPushedFcn = createCallbackFcn(app, @A5ButtonPushed, true);
            app.A5Button.Text = 'A5';
            app.B5Button = uibutton(app.UIFigure, 'push');
            app.B5Button.ButtonPushedFcn = createCallbackFcn(app, @B5ButtonPushed, true);
            app.B5Button.Text = 'B5';
            app.C6Button = uibutton(app.UIFigure, 'push');
            app.C6Button.ButtonPushedFcn = createCallbackFcn(app, @C6ButtonPushed, true);
            app.C6Button.Text = 'C6';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '';
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '';
            app.Button_10 = uibutton(app.UIFigure, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '';
            app.Button_11 = uibutton(app.UIFigure, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = '';
            app.Button_12 = uibutton(app.UIFigure, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Text = '';
            app.Button_13 = uibutton(app.UIFigure, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @Button_13Pushed, true);
            app.Button_13.Text = '';
            app.PIANOLabel = uilabel(app.UIFigure);
            app.PIANOLabel.Text = 'PIANO';
            app.RecordButton = uibutton(app.UIFigure, 'state');
            app.RecordButton.ValueChangedFcn = createCallbackFcn(app, @RecordButtonValueChanged, true);
            app.RecordButton.Text = 'Record';
            app.SHFTLabel = uilabel(app.UIFigure);
            app.SHFTLabel.Text = 'SHFT';
            app.ZLabel = uilabel(app.UIFigure);
            app.ZLabel.Text = 'Z';
            app.XLabel = uilabel(app.UIFigure);
            app.XLabel.Text = 'X';
            app.CLabel = uilabel(app.UIFigure);
            app.CLabel.Text = 'C';
            app.VLabel = uilabel(app.UIFigure);
            app.VLabel.Text = 'V';
            app.BLabel = uilabel(app.UIFigure);
            app.BLabel.Text = 'B';
            app.NLabel = uilabel(app.UIFigure);
            app.NLabel.Text = 'N';
            app.MLabel = uilabel(app.UIFigure);
            app.MLabel.Text = 'M';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = ',';
            app.LLabel = uilabel(app.UIFigure);
            app.LLabel.Text = 'L';
            app.KLabel = uilabel(app.UIFigure);
            app.KLabel.Text = 'K';
            app.JLabel = uilabel(app.UIFigure);
            app.JLabel.Text = 'J';
            app.HLabel = uilabel(app.UIFigure);
            app.HLabel.Text = 'H';
            app.GLabel = uilabel(app.UIFigure);
            app.GLabel.Text = 'G';
            app.FLabel = uilabel(app.UIFigure);
            app.FLabel.Text = 'F';
            app.DLabel = uilabel(app.UIFigure);
            app.DLabel.Text = 'D';
            app.SLabel = uilabel(app.UIFigure);
            app.SLabel.Text = 'S';
            app.ALabel = uilabel(app.UIFigure);
            app.ALabel.Text = 'A';
            app.CLLabel = uilabel(app.UIFigure);
            app.CLLabel.Text = 'CL';
            app.QLabel = uilabel(app.UIFigure);
            app.QLabel.Text = 'Q';
            app.WLabel = uilabel(app.UIFigure);
            app.WLabel.Text = 'W';
            app.ELabel = uilabel(app.UIFigure);
            app.ELabel.Text = 'E';
            app.RLabel = uilabel(app.UIFigure);
            app.RLabel.Text = 'R';
            app.TLabel = uilabel(app.UIFigure);
            app.TLabel.Text = 'T';
            app.YLabel = uilabel(app.UIFigure);
            app.YLabel.Text = 'Y';
            app.ULabel = uilabel(app.UIFigure);
            app.ULabel.Text = 'U';
            app.ILabel = uilabel(app.UIFigure);
            app.ILabel.Text = 'I';
            app.OLabel = uilabel(app.UIFigure);
            app.OLabel.Text = 'O';
            app.PLabel = uilabel(app.UIFigure);
            app.PLabel.Text = 'P';
            app.INSLabel = uilabel(app.UIFigure);
            app.INSLabel.Text = 'INS';
            app.HMLabel = uilabel(app.UIFigure);
            app.HMLabel.Text = 'HM';
            app.ENDLabel = uilabel(app.UIFigure);
            app.ENDLabel.Text = 'END';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pianio_interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end